classdef siscan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        SINGLESHOTDSCANLabel            matlab.ui.control.Label
        InitializeButton                matlab.ui.control.Button
        SingleImageButton               matlab.ui.control.Button
        ExitCameraButton                matlab.ui.control.Button
        ExposuremsSpinnerLabel          matlab.ui.control.Label
        ExposuremsSpinner               matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        FramerateSpinnerLabel           matlab.ui.control.Label
        FramerateSpinner                matlab.ui.control.Spinner
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        CAMERACONTROLSLabel             matlab.ui.control.Label
        INDICATORSLabel                 matlab.ui.control.Label
        ExposuremsEditFieldLabel        matlab.ui.control.Label
        ExposuremsEditField             matlab.ui.control.NumericEditField
        LiveViewButton                  matlab.ui.control.StateButton
        SaveScanButton                  matlab.ui.control.Button
        UploadnewreferenceButton        matlab.ui.control.Button
        DATACONTROLSLabel               matlab.ui.control.Label
        SetreferenceButton              matlab.ui.control.Button
        SaveLabel                       matlab.ui.control.Label
        LoadLabel                       matlab.ui.control.Label
        UIAxes9                         matlab.ui.control.UIAxes
        TreatmentTab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        LoadScanButton                  matlab.ui.control.Button
        UseMeasuredButton               matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        LoadScanningButton              matlab.ui.control.Button
        LoadFundamentalButton_2         matlab.ui.control.Button
        DATATREATMENTLabel              matlab.ui.control.Label
        ProcessScanButton               matlab.ui.control.Button
        OptionalLabel                   matlab.ui.control.Label
        LoadwavelengthcalibrationfileButton  matlab.ui.control.Button
        LoadglasscalibrationfileButton  matlab.ui.control.Button
        orLabel                         matlab.ui.control.Label
        thenLabel                       matlab.ui.control.Label
        andLabel                        matlab.ui.control.Label
        RetrievalTab                    matlab.ui.container.Tab
        THERETRIEVALLabel               matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        RetrieveButton                  matlab.ui.control.Button
        TweakParametersLabel            matlab.ui.control.Label
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        TabGroup2                       matlab.ui.container.TabGroup
        TimedomainTab                   matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        FrequencydomainTab              matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        RETRIEVEDFIELDLabel             matlab.ui.control.Label
        saveEtButton                    matlab.ui.control.Button
        saveEwButton                    matlab.ui.control.Button
        saveeverythingButton            matlab.ui.control.Button
        SAVERETRIEVALSLabel             matlab.ui.control.Label
        N_iterEditFieldLabel            matlab.ui.control.Label
        N_iterEditField                 matlab.ui.control.NumericEditField
        StopButton                      matlab.ui.control.Button
        UsefundamentalButton            matlab.ui.control.StateButton
    end


    properties (Access = private)
        
        oldpath % path with functions
        IDScam; % Camera object
        fpsdef % default fps
        expdef % default exposure
        pxlclockdef % default pxl clock
        
        img % measured scan
        cmp ;% default colormap - thermal
        ld  %loaded scan
        scan % scan
        fnspec %fundamental spectrum
        bgvalue %background
        wlcab = []; %wavelength calibration
        zcab  % glass insertion calibration


        Ew % retrieved field in freq domain
        Et % retrieved field in time domain
        simscan %simulated scan by retrieval
        time % time axis in retrieved field
        freq
        
        Niter % max number of iterations in retrieval
        ref % reference
        pathref %path to the reference
        usefund % in case you want to use fundamental spectrum in retrieval
        stopvalue %


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oldpath = addpath('C:\Users\atto\Documents\src\LabPrograms\lib\siscan_functions'); % add path with functions
            
            %default parameters
            app.wlcab =  load('wavelength.mat','wl');
            app.cmp = cmocean('thermal');
            app.bgvalue = 8;
            app.zcab = linspace(-3,3,256);
            app.Niter = 20;
            app.fpsdef = 12;
            app.expdef = 0.25;
            app.pxlclockdef = 160;
            
            %load old reference on startup
            app.pathref = fullfile('C:\Users\atto\Documents\src\LabPrograms\lib\siscan_functions','ref.png'); %edit the filepath
            app.ref = imread(app.pathref);

            
            % Display image and stretch to fill axes
            xlabel(app.UIAxes9, []);
            ylabel(app.UIAxes9, []);
            I = imshow(app.ref, 'Parent', app.UIAxes9,'colormap',app.cmp);
            % Set limits of axes
            app.UIAxes9.XLim = [0 I.XData(2)];
            app.UIAxes9.YLim = [0 I.YData(2)];
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            
            app.InitializeButton.FontColor = 'green';
            app.IDScam = IDSCamera('4103393584');
            app.IDScam.init();
            app.IDScam.LoadParameterSet('C:\Users\atto\Documents\src\LabPrograms\lib\siscan_functions\camerasettings.ini')
%             app.IDScam.setFramerate(app.fpsdef);
%             app.IDScam.setExposure(app.expdef*1e-3);
%             app.IDScam.setPixelClock(app.pxlclockdef);
%             app.IDScam.EnableExtRange('On')
            app.ExposuremsSpinner.Enable = 'on';
            app.IDScam.setColorMode('SensorRaw8') %sets the colormode
            
        end

        % Button pushed function: SingleImageButton
        function SingleImageButtonPushed(app, event)
            app.IDScam.getImageAsyncStartCam();
            app.img = app.IDScam.getImageAsyncEndCam();
            imshow(app.img,'Parent',app.UIAxes,'colormap',app.cmp)
        end

        % Button pushed function: ExitCameraButton
        function ExitCameraButtonPushed(app, event)
            app.IDScam.delete();
            app.InitializeButton.FontColor = 'k';
            
        end

        % Value changed function: ExposuremsSpinner
        function ExposuremsSpinnerValueChanged(app, event)
            value = app.ExposuremsSpinner.Value;
            app.IDScam.setExposure(value*1e-3)
            fprintf('Exposure set to %.1f ms.\n',value)
            expTime = getExposure(app.IDScam);
            app.ExposuremsEditField.Value = expTime*1e3;
        end

        % Value changed function: LiveViewButton
        function LiveViewButtonValueChanged(app, event)
            
            
            %live view through .net implementation
            app.IDScam.startLiveView();
            app.LiveViewButton.FontColor = 'r';
            value = app.LiveViewButton.Value;
            
            while value
                if ~value
                    app.IDScam.stopLiveView();
                    
                    break
                end
                app.img = app.IDScam.extractImage();

                I = imshow(app.img,'Parent',app.UIAxes,'colormap',app.cmp);
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                %
                pause(1e-5);
                value = app.LiveViewButton.Value;
            end
            app.LiveViewButton.FontColor = 'k';
            %
        end

        % Value changed function: FramerateSpinner
        function FramerateSpinnerValueChanged(app, event)
            value = app.FramerateSpinner.Value;
            app.IDScam.setFramerate(value)
            fprintf('Framerate set to %.1f Hz.\n',value)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            switch value
                case 'Monochrome'
                    app.cmp = colormap(app.UIAxes,gray);
                case 'Parula'
                    app.cmp = colormap(app.UIAxes,parula);
                case 'Hot'
                    app.cmp = colormap(app.UIAxes,hot);
                case 'Winter'
                    app.cmp = colormap(app.UIAxes,winter);
                case 'Jet'
                    app.cmp = colormap(app.UIAxes,jet);
                case 'Thermal'
                    app.cmp = colormap(app.UIAxes,cmocean('thermal'));
                    
            end
        end

        % Button pushed function: SaveScanButton
        function SaveScanButtonPushed(app, event)
            filter = {'*.png';'*.jpg';'*.fig';'*.bmp';'*.*'};
            [file,path] = uiputfile(filter,'Save Scan');
            
            if isequal(file,0) || isequal(path,0)
                disp('Cancelled.')
            else
                dscan = fullfile(path,file);
                imwrite(app.img,app.cmp,dscan);
            end


        end

        % Button pushed function: LoadScanButton
        function LoadScanButtonPushed(app, event)
            
            filter = {'*.png';'*.jpg';'*.fig'};
            [file,path] = uigetfile(filter,'Get File','C:\Data\scan.png');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if isequal(file,0)
                disp('Cancelled')
            else
                app.ld = imread(fullfile(path,file));
                app.scan = app.ld;
                colormap(app.UIAxes2,app.cmp)
                imagesc(app.wlcab.wl,app.zcab,app.scan,'Parent',app.UIAxes2);
                xlim(app.UIAxes2, [app.wlcab.wl(1) app.wlcab.wl(end)])
                ylim(app.UIAxes2,[app.zcab(1) app.zcab(end)])
                xlabel(app.UIAxes2,'\lambda, nm')
                ylabel(app.UIAxes2,'glass, mm')
            end
            
        end

        % Button pushed function: LoadFundamentalButton_2
        function LoadFundamentalButton_2Pushed(app, event)
            app.fnspec = getFundamental();
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            plot(app.fnspec(:,1),app.fnspec(:,2),'k','Parent',app.UIAxes3);
            title(app.UIAxes3,'Fundamental spectrum')
            xlim(app.UIAxes3, [0.2 0.6])
            
            
        end

        % Button pushed function: UseMeasuredButton
        function UseMeasuredButtonPushed(app, event)
            
            app.scan = app.img;
            colormap(app.UIAxes2,app.cmp)
            imagesc(app.wlcab,app.zcab,app.scan,'Parent',app.UIAxes2);
            
        end

        % Button pushed function: ProcessScanButton
        function ProcessScanButtonPushed(app, event)
            z = app.zcab;
            wl = app.wlcab.wl;
            app.scan = processScan(app.scan,app.fnspec,z,wl);
            fshg = (app.fnspec(:,1)+app.fnspec(1,1))';
            colormap(app.UIAxes2,app.cmp)
            imagesc(fshg',z,app.scan,'Parent',app.UIAxes2,'XData', [fshg(1) fshg(end)],'YData', [z(1) z(end)]);
            ylim(app.UIAxes2,[z(1) z(end)])
            xlim(app.UIAxes2, [0.6 1] )
            xlabel(app.UIAxes2,'Frequency, PHz')
            ylabel(app.UIAxes2,'glass, mm')
            
            colormap(app.UIAxes5,app.cmp)
            imagesc(fshg',z,app.scan,'Parent',app.UIAxes5,'XData', [fshg(1) fshg(end)],'YData', [z(1) z(end)]);
            ylim(app.UIAxes5,[z(1) z(end)])
            xlim(app.UIAxes5, [0.6 1] )
            
            
        end

        % Button pushed function: RetrieveButton
        function RetrieveButtonPushed(app, event)

            reset(app.UIAxes7) % resets the retrieved fields axes
            
            reset(app.UIAxes8)

            app.StatusLamp.Color = 'red';
            
            imag = app.scan; %measured and processed scan
            
            z = app.zcab; %glass insertion range 
            
            N = app.Niter; %max number of iterations
            
            f = app.fnspec(:,1)'; %frequency axis from fundamental spectrum
            
            app.freq = f; % for later saving
            
            Int = app.fnspec(:,2)';
            
            iter = 1; % counter
            
            Err = 1; %rms error starting value
            
            fshg = f+min(f)'; %shg frequencies
            
            wl_f = 299.792./f; %wavelength vector for the phase mask
            
            imag = max(0,imag); %filter negative values
            
            k = 2*pi.*nBK7(wl_f./1000)./(wl_f*1e-9);

            phase = (z*1e-3)'*k;
            
            Meas=sqrt(imag); %amplitude of the trace
            
            int_z=sum(imag,2);
            
            int_z=int_z/sum(int_z); %normalize
            
            intzz=wextend('ac','sp0',int_z,length(f)-1,'d');
            
            idz=int_z==max(int_z);
            
            GGuess=abs(ifft(sqrt(fft(Meas(idz,:),[],2)),[],2));
            
            
            while Err>0.001 
               
                GGuess = kron(GGuess,ones(length(z),1)); %extend pulse vector into matrix
                
                Gt = ifft(GGuess.*exp(1i.*phase),[],2); %to time domain. eq(8) and eq(9)
                
                Gshgw = fft(Gt.^2,[],2); %generate shg and go to frequency domain. eq(11)
                
                Gup = Meas.*exp(1i.*angle(Gshgw)); %multiply by measured intensity. eq(12)
                
                Gtr = ifft(Gup,[],2); %to time domain. eq(13)
                
                P = Gtr.*conj(Gt); %P coeff. eq(14)
                
                Gt2 = abs(P.^(1/3)).*exp(1i.*angle(P)); %next guess. eq(15)
                
                Gw2 = fft(Gt2,[],2).*exp(-1i.*phase); %to frequency domain and remove phase from glass. eq(16) and (17)
                
                GGuess = sum(Gw2.*intzz,1);
                
                  if app.usefund  
                    GGuess = sqrt(Int).*exp(1i.*angle(GGuess)); %multiply by fundamental if user pushed respective button
                  end
                
                %error estimation
                
                retr = SHGv21(phase,abs(GGuess).^2,angle(GGuess)); %normalized retrieved scan
                
                Err=1-sum(sum(sqrt(imag.*retr)))/sqrt(sum(sum(retr))*sum(sum(imag)));
                
                iter = iter+1;


                colormap(app.UIAxes6,app.cmp)
                 imagesc(fshg,z,retr,'Parent',app.UIAxes6,'XData', [fshg(1) fshg(end)],'YData', [z(1) z(end)]);
                title(['iter=',num2str(iter), 'Error=',num2str(Err)],'Parent',app.UIAxes6);
                ylim(app.UIAxes6,[z(1) z(end)])
                xlim(app.UIAxes6, [0.6 1] )
                ylabel('glass,mm','Parent',app.UIAxes6)
                xlabel('frequency, PHz','Parent',app.UIAxes6)
                drawnow;
                
                if iter>=N
                    break
                end
                
            end
            app.simscan = retr;
            
            app.Ew = GGuess; % add retrieved field as an app property
            
            app.stopvalue = false;
            app.StatusLamp.Color = 'green';
            
            %plot retrieved field in spectral domain
            
            phase = angle(GGuess);
            phase = unwrap(phase);
            
            plot(app.UIAxes8,f,abs(GGuess).^2./max(abs(GGuess).^2.))
            title(app.UIAxes8,'Spectral domain')
            hold(app.UIAxes8,'on')
            plot(app.UIAxes8,f,Int,'k--')
            yyaxis(app.UIAxes8,'left')
            ylabel(app.UIAxes8,'Spectral power, a.u.')
            xlabel(app.UIAxes8,'freq, PHz')
            legend(app.UIAxes8,'Retrieved', 'Measured')
            yyaxis(app.UIAxes8,'right')
            
            plot(app.UIAxes8,f,phase)
            ylabel(app.UIAxes8,'Spectral phase, rad')
            xlim(app.UIAxes8,[0.2 0.6])
            legend(app.UIAxes8,'Retrieved', 'Measured','Phase')
            hold(app.UIAxes8,'off')
            
            %plot retrieved field in temporal domain
            Np = length(f);
            dt = 1./abs((f(1)- f(end))).*Np;
            t = (dt/Np)*((-Np/2 + 1):Np/2);
            app.time = t;
            
            
            Ret = ifft(GGuess);
            Ret = ifftshift(Ret);
            app.Et = Ret;
            phase = unwrap(angle(Ret));
            t2 = linspace(t(1),t(end),1920);
            Inten = abs(Ret).^2./max(abs(Ret).^2);

            Inten = interp1(t,Inten,t2,'linear');
            phase = interp1(t,phase,t2,'linear');
            [~,idx] = max(Inten);
            t2 = t2 + t2(end-idx);
            plot(app.UIAxes7,t2,Inten)
            
            idx = 1;
            
            while idx<length(t2)/2
                idx = idx + 1;
                if Inten(idx)>0.5
                    break
                end
                
            end
            y1 = t2(idx);
            
            idx2 = length(t2);
            
            while idx2>length(t2)/2
                idx2 = idx2 - 1;
                if Inten(idx2)>0.5
                    break
                end
                
            end
            
            y2 = t2(idx2);
            
            FWHM = y2-y1;
            
            
            hold(app.UIAxes7,'on')
            yyaxis(app.UIAxes7,'left')
            title (app.UIAxes7,['Temporal intensity profile, FWHM: ' num2str(FWHM,3) ' fs'])
            ylabel(app.UIAxes7,'Intensity, a.u.')
            xlabel(app.UIAxes7,'time, fs')
            ylim(app.UIAxes7,[0 1.2])
            
            yyaxis(app.UIAxes7,'right')
            
            plot(app.UIAxes7,t2,phase)
            ylabel(app.UIAxes7,'Temporal phase, rad')
            xlim(app.UIAxes7,[-50 50])
            hold(app.UIAxes7,'off')


        end

        % Button pushed function: LoadScanningButton
        function LoadScanningButtonPushed(app, event)
            filter = {'*.fig';'*.png';'*.jpg'};
            [file,path] = uigetfile(filter,'Get File','C:\Data\scan.jpg');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if isequal(file,0)
                disp('Cancelled')
            else
                scanning = imread(fullfile(path,file));
                imshow(scanning,'Parent',app.UIAxes4,'colormap',app.cmp);
            end
        end

        % Button pushed function: LoadwavelengthcalibrationfileButton
        function LoadwavelengthcalibrationfileButtonPushed(app, event)
            filter = '*.mat';% test these
            [file,path] = uigetfile(filter,'Get file','C:\Data\scan.mat');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if isequal(file,0)
                disp('Cancelled')
            else
                app.wlcab = load(fullfile(path,file));
            end
        end

        % Button pushed function: LoadglasscalibrationfileButton
        function LoadglasscalibrationfileButtonPushed(app, event)
            filter = '*.mat'; %test these
            [file,path] = uigetfile(filter,'Get file','C:\Data\scan.mat');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if isequal(file,0)
                disp('Cancelled')
            else
                app.zcab = load(fullfile(path,file));
            end
        end

        % Value changed function: N_iterEditField
        function N_iterEditFieldValueChanged(app, event)
            value = app.N_iterEditField.Value;
            app.Niter = value;
        end

        % Button pushed function: SetreferenceButton
        function SetreferenceButtonPushed(app, event)
            app.pathref = fullfile('D:\PhD\Programmes\MATLAB\MHzgeneral\lib\siscan_functions','ref.png');
            imwrite(app.img,app.cmp,app.pathref);
            imshow(app.img,'Parent',app.UIAxes9,'colormap',app.cmp);
        end

        % Button pushed function: UploadnewreferenceButton
        function UploadnewreferenceButtonPushed(app, event)
             filter = {'*.fig';'*.png';'*.jpg'};
            [file,path] = uigetfile(filter,'Get File','C:\Data\scan.jpg');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if isequal(file,0)
                disp('Cancelled')
            else
                newref = imread(fullfile(path,file));
                imshow(newref,'Parent',app.UIAxes9,'colormap',app.cmp);
            end
            app.pathref = fullfile('D:\PhD\Programmes\MATLAB\MHzgeneral\lib\siscan_functions','ref.png');
            imwrite(newref,app.cmp,app.pathref);
            imshow(newref,'Parent',app.UIAxes9,'colormap',app.cmp);
        end

        % Value changed function: UsefundamentalButton
        function UsefundamentalButtonValueChanged(app, event)
            app.usefund = app.UsefundamentalButton.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopvalue = true;
        end

        % Button pushed function: saveEtButton
        function saveEtButtonPushed(app, event)
            filter = '*.mat';
            [file,path] = uiputfile(filter,'Save E_t');
            
            if isequal(file,0) || isequal(path,0)
                disp('Cancelled.')
            else
                dscan = fullfile(path,file);
                E_t = app.Et;
                t = app.time;
                save(dscan,'E_t','t');
            end
        end

        % Button pushed function: saveEwButton
        function saveEwButtonPushed(app, event)
            filter = '*.mat';
            [file,path] = uiputfile(filter,'Save E_w');
            
            if isequal(file,0) || isequal(path,0)
                disp('Cancelled.')
            else
                dscan = fullfile(path,file);
                E_w = app.Ew;
                nu = app.freq;
                save(dscan,'E_w','nu');
            end
        end

        % Button pushed function: saveeverythingButton
        function saveeverythingButtonPushed(app, event)
            filter = '*.mat';
            [file,path] = uiputfile(filter,'Save EVERYTHING!');
            
            if isequal(file,0) || isequal(path,0)
                disp('Cancelled.')
            else
                dscan = fullfile(path,file);
                E_t = app.Et;
                t = app.time;
                E_w = app.Ew;
                nu = app.freq;
                ret = app.simscan;
                Exp = app.scan;
                fund = app.fnspec;
                save(dscan,'E_t','t','E_w','nu','ret','Exp','fund');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.SINGLESHOTDSCANLabel = uilabel(app.AcquisitionTab);
            app.SINGLESHOTDSCANLabel.Text = 'SINGLE-SHOT DSCAN';
            app.InitializeButton = uibutton(app.AcquisitionTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.SingleImageButton = uibutton(app.AcquisitionTab, 'push');
            app.SingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @SingleImageButtonPushed, true);
            app.SingleImageButton.Text = 'Single Image';
            app.ExitCameraButton = uibutton(app.AcquisitionTab, 'push');
            app.ExitCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ExitCameraButtonPushed, true);
            app.ExitCameraButton.Text = 'Exit Camera';
            app.ExposuremsSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsSpinnerLabel.Text = 'Exposure, ms';
            app.ExposuremsSpinner = uispinner(app.AcquisitionTab);
            app.ExposuremsSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposuremsSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'Scan')
            app.FramerateSpinnerLabel = uilabel(app.AcquisitionTab);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.AcquisitionTab);
            app.FramerateSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinnerValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.AcquisitionTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.AcquisitionTab);
            app.ColormapDropDown.Items = {'Parula', 'Monochrome', 'Thermal', 'Jet', 'Hot', 'Winter'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Monochrome';
            app.CAMERACONTROLSLabel = uilabel(app.AcquisitionTab);
            app.CAMERACONTROLSLabel.Text = 'CAMERA CONTROLS';
            app.INDICATORSLabel = uilabel(app.AcquisitionTab);
            app.INDICATORSLabel.Text = 'INDICATORS';
            app.ExposuremsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsEditFieldLabel.Text = 'Exposure, ms';
            app.ExposuremsEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.ExposuremsEditField.Editable = 'off';
            app.LiveViewButton = uibutton(app.AcquisitionTab, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewButtonValueChanged, true);
            app.LiveViewButton.Text = 'Live View';
            app.SaveScanButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveScanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScanButtonPushed, true);
            app.SaveScanButton.Text = 'Save Scan';
            app.UploadnewreferenceButton = uibutton(app.AcquisitionTab, 'push');
            app.UploadnewreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @UploadnewreferenceButtonPushed, true);
            app.UploadnewreferenceButton.Text = 'Upload new reference';
            app.DATACONTROLSLabel = uilabel(app.AcquisitionTab);
            app.DATACONTROLSLabel.Text = 'DATA CONTROLS';
            app.SetreferenceButton = uibutton(app.AcquisitionTab, 'push');
            app.SetreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SetreferenceButtonPushed, true);
            app.SetreferenceButton.Text = 'Set reference';
            app.SaveLabel = uilabel(app.AcquisitionTab);
            app.SaveLabel.Text = 'Save';
            app.LoadLabel = uilabel(app.AcquisitionTab);
            app.LoadLabel.Text = 'Load';
            app.UIAxes9 = uiaxes(app.AcquisitionTab);
            app.TreatmentTab = uitab(app.TabGroup);
            app.TreatmentTab.Title = 'Treatment';
            app.UIAxes2 = uiaxes(app.TreatmentTab);
            title(app.UIAxes2, 'SIScan')
            ylabel(app.UIAxes2, 'Glass insertion')
            app.UIAxes3 = uiaxes(app.TreatmentTab);
            title(app.UIAxes3, 'Spectrum')
            ylabel(app.UIAxes3, 'Intensity, a.u.')
            app.LoadScanButton = uibutton(app.TreatmentTab, 'push');
            app.LoadScanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanButtonPushed, true);
            app.LoadScanButton.Text = 'Load Scan';
            app.UseMeasuredButton = uibutton(app.TreatmentTab, 'push');
            app.UseMeasuredButton.ButtonPushedFcn = createCallbackFcn(app, @UseMeasuredButtonPushed, true);
            app.UseMeasuredButton.Text = 'Use Measured';
            app.UIAxes4 = uiaxes(app.TreatmentTab);
            app.LoadScanningButton = uibutton(app.TreatmentTab, 'push');
            app.LoadScanningButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanningButtonPushed, true);
            app.LoadScanningButton.Text = 'Load Scanning';
            app.LoadFundamentalButton_2 = uibutton(app.TreatmentTab, 'push');
            app.LoadFundamentalButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFundamentalButton_2Pushed, true);
            app.LoadFundamentalButton_2.Text = 'Load Fundamental';
            app.DATATREATMENTLabel = uilabel(app.TreatmentTab);
            app.DATATREATMENTLabel.Text = 'DATA TREATMENT';
            app.ProcessScanButton = uibutton(app.TreatmentTab, 'push');
            app.ProcessScanButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessScanButtonPushed, true);
            app.ProcessScanButton.Text = 'Process Scan';
            app.OptionalLabel = uilabel(app.TreatmentTab);
            app.OptionalLabel.Text = 'Optional';
            app.LoadwavelengthcalibrationfileButton = uibutton(app.TreatmentTab, 'push');
            app.LoadwavelengthcalibrationfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadwavelengthcalibrationfileButtonPushed, true);
            app.LoadwavelengthcalibrationfileButton.Text = 'Load wavelength calibration file';
            app.LoadglasscalibrationfileButton = uibutton(app.TreatmentTab, 'push');
            app.LoadglasscalibrationfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadglasscalibrationfileButtonPushed, true);
            app.LoadglasscalibrationfileButton.Text = 'Load glass calibration file';
            app.orLabel = uilabel(app.TreatmentTab);
            app.orLabel.Text = 'or';
            app.thenLabel = uilabel(app.TreatmentTab);
            app.thenLabel.Text = 'then';
            app.andLabel = uilabel(app.TreatmentTab);
            app.andLabel.Text = 'and';
            app.RetrievalTab = uitab(app.TabGroup);
            app.RetrievalTab.Title = 'Retrieval';
            app.THERETRIEVALLabel = uilabel(app.RetrievalTab);
            app.THERETRIEVALLabel.Text = 'THE RETRIEVAL';
            app.UIAxes5 = uiaxes(app.RetrievalTab);
            title(app.UIAxes5, 'Measured')
            ylabel(app.UIAxes5, 'glass, mm')
            app.UIAxes6 = uiaxes(app.RetrievalTab);
            title(app.UIAxes6, 'Retrieved')
            ylabel(app.UIAxes6, 'glass, mm')
            app.RetrieveButton = uibutton(app.RetrievalTab, 'push');
            app.RetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPushed, true);
            app.RetrieveButton.Text = 'Retrieve!';
            app.TweakParametersLabel = uilabel(app.RetrievalTab);
            app.TweakParametersLabel.Text = 'Tweak Parameters';
            app.StatusLampLabel = uilabel(app.RetrievalTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.RetrievalTab);
            app.TabGroup2 = uitabgroup(app.RetrievalTab);
            app.TimedomainTab = uitab(app.TabGroup2);
            app.TimedomainTab.Title = 'Time domain';
            app.UIAxes7 = uiaxes(app.TimedomainTab);
            title(app.UIAxes7, 'Temporal')
            xlabel(app.UIAxes7, 'time,fs')
            ylabel(app.UIAxes7, 'Intensity, a.u.')
            app.FrequencydomainTab = uitab(app.TabGroup2);
            app.FrequencydomainTab.Title = 'Frequency domain';
            app.UIAxes8 = uiaxes(app.FrequencydomainTab);
            title(app.UIAxes8, 'Spectral')
            ylabel(app.UIAxes8, 'Intensity, a.u.')
            app.RETRIEVEDFIELDLabel = uilabel(app.RetrievalTab);
            app.RETRIEVEDFIELDLabel.Text = 'RETRIEVED FIELD';
            app.saveEtButton = uibutton(app.RetrievalTab, 'push');
            app.saveEtButton.ButtonPushedFcn = createCallbackFcn(app, @saveEtButtonPushed, true);
            app.saveEtButton.Text = 'save E(t)';
            app.saveEwButton = uibutton(app.RetrievalTab, 'push');
            app.saveEwButton.ButtonPushedFcn = createCallbackFcn(app, @saveEwButtonPushed, true);
            app.saveEwButton.Text = 'save E(w)';
            app.saveeverythingButton = uibutton(app.RetrievalTab, 'push');
            app.saveeverythingButton.ButtonPushedFcn = createCallbackFcn(app, @saveeverythingButtonPushed, true);
            app.saveeverythingButton.Text = 'save everything';
            app.SAVERETRIEVALSLabel = uilabel(app.RetrievalTab);
            app.SAVERETRIEVALSLabel.Text = 'SAVE RETRIEVALS';
            app.N_iterEditFieldLabel = uilabel(app.RetrievalTab);
            app.N_iterEditFieldLabel.Text = 'N_iter';
            app.N_iterEditField = uieditfield(app.RetrievalTab, 'numeric');
            app.N_iterEditField.ValueChangedFcn = createCallbackFcn(app, @N_iterEditFieldValueChanged, true);
            app.N_iterEditField.Value = 20;
            app.StopButton = uibutton(app.RetrievalTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UsefundamentalButton = uibutton(app.RetrievalTab, 'state');
            app.UsefundamentalButton.ValueChangedFcn = createCallbackFcn(app, @UsefundamentalButtonValueChanged, true);
            app.UsefundamentalButton.Text = 'Use fundamental';
        end
    end

    methods (Access = public)

        % Construct app
        function app = siscan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end